classdef CK_Ten < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        NghimTab                       matlab.ui.container.Tab
        DrawButton                     matlab.ui.control.Button
        KtqunghimEditFieldLabel        matlab.ui.control.Label
        KtqunghimEditField             matlab.ui.control.NumericEditField
        SlnlpEditFieldLabel            matlab.ui.control.Label
        SlnlpEditField                 matlab.ui.control.NumericEditField
        ChnphngphptmChiailpNewtontiptuynEditFieldLabel  matlab.ui.control.Label
        ChnphngphptmChiailpNewtontiptuynEditField  matlab.ui.control.EditField
        NhpphngtrnhEditFieldLabel      matlab.ui.control.Label
        NhpphngtrnhEditField           matlab.ui.control.EditField
        NhpkhongphnlynghimEditFieldLabel  matlab.ui.control.Label
        NhpkhongphnlynghimEditField    matlab.ui.control.EditField
        NhpsaischophpEditField_3Label  matlab.ui.control.Label
        NhpsaischophpEditField         matlab.ui.control.EditField
        UIAxes                         matlab.ui.control.UIAxes
        NisuyTab                       matlab.ui.container.Tab
        DrawButton_2                   matlab.ui.control.Button
        NhpsaischophpEditField_2Label  matlab.ui.control.Label
        NhpsaischophpEditField_2       matlab.ui.control.NumericEditField
        KtqunghimEditField_2Label      matlab.ui.control.Label
        KtqunghimEditField_2           matlab.ui.control.NumericEditField
        SlnlpEditField_2Label          matlab.ui.control.Label
        SlnlpEditField_2               matlab.ui.control.NumericEditField
        ChnphngphptmChiailpNewtontiptuynEditField_2Label  matlab.ui.control.Label
        ChnphngphptmChiailpNewtontiptuynEditField_2  matlab.ui.control.EditField
        NhpphngtrnhEditField_2Label    matlab.ui.control.Label
        NhpphngtrnhEditField_2         matlab.ui.control.EditField
        NhpkhongphnlynghimEditField_2Label  matlab.ui.control.Label
        NhpkhongphnlynghimEditField_2  matlab.ui.control.EditField
        UIAxes_2                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
           % Đọc hàm số từ trường nhập liệu
            funcStr = app.NhpphngtrnhEditField.Value;
            f = str2func(['@(x)', funcStr]);  % Chuyển chuỗi thành hàm ẩn danh
            
            % Đọc khoảng x từ trường nhập liệu
            rangeStr = app.NhpkhongphnlynghimEditField.Value;
            
            % Loại bỏ khoảng trắng thừa và chuyển chuỗi thành vector số
            rangeStr = strrep(rangeStr, ' ', '');  % Xóa khoảng trắng
            range = str2num(rangeStr);  % Chuyển chuỗi thành vector số
            
            % Kiểm tra xem vector có đúng hai phần tử hay không
            if length(range) ~= 2
                uialert(app.UIFigure, 'Nhập khoảng x không đúng. Nhập lại dạng "a,b".', 'Lỗi nhập liệu');
                return;
            end

            % Đọc sai số cho phép từ trường nhập liệu
            toleranceStr = app.NhpsaischophpEditField.Value;  % Giả sử trường nhập liệu sai số cho phép có tên là NhpsaisochophpEditField
            tolerance = str2double(toleranceStr);  % Chuyển đổi chuỗi thành số
            
            % Kiểm tra xem sai số đã được nhập hợp lệ hay chưa
            if isnan(tolerance) || tolerance <= 0
                uialert(app.UIFigure, 'Nhập sai số cho phép không hợp lệ. Sai số phải là một số dương.', 'Lỗi nhập liệu');
                return;
            end

            % Đọc phương pháp tìm nghiệm từ dropdown
            method = app.ChnphngphptmChiailpNewtontiptuynEditField.Value;
            
            % Kiểm tra nhập liệu và vẽ đồ thị
            if length(range) == 2
                fplot(app.UIAxes, f, range);
                grid(app.UIAxes, 'on');
                title(app.UIAxes, 'Đồ thị hàm số');
                xlabel(app.UIAxes, 'X');
                ylabel(app.UIAxes, 'Y');
            else
                uialert(app.UIFigure, 'Nhập khoảng x không đúng. Nhập lại dạng "a,b".', 'Lỗi nhập liệu');
                return;
            end
            
            % Tính toán nghiệm và số lần lặp tùy theo phương pháp
            switch method
                case 'Chia đôi'
                    [root, iterations] = ChiaDoi(f, range(1), range(2), tolerance);
                case 'Lặp'
                    initialGuess = (range(1) + range(2)) / 2; % Giả sử điểm khởi đầu là điểm giữa khoảng
                    [root, iterations] = lap(f, f, initialGuess, tolerance); % Giả sử bạn đã sửa hàm lap để sử dụng
                case 'Newton'
                    initialGuess = (range(1) + range(2)) / 2;
                    [root, iterations] = tieptuyen(f, initialGuess, 0, tolerance);
                otherwise
                    uialert(app.UIFigure, 'Phương pháp không được hỗ trợ.', 'Lỗi');
                    return;
            end
            
            % Hiển thị kết quả nghiệm và số lần lặp
            app.KtqunghimEditField.Value = num2str(root);
            app.SlnlpEditField.Value = num2str(iterations); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghimTab = uitab(app.TabGroup);
            app.NghimTab.Title = 'Nghiệm';
            app.DrawButton = uibutton(app.NghimTab, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Text = 'Draw';
            app.KtqunghimEditFieldLabel = uilabel(app.NghimTab);
            app.KtqunghimEditFieldLabel.Text = 'Kết quả nghiệm';
            app.KtqunghimEditField = uieditfield(app.NghimTab, 'numeric');
            app.SlnlpEditFieldLabel = uilabel(app.NghimTab);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.SlnlpEditField = uieditfield(app.NghimTab, 'numeric');
            app.ChnphngphptmChiailpNewtontiptuynEditFieldLabel = uilabel(app.NghimTab);
            app.ChnphngphptmChiailpNewtontiptuynEditFieldLabel.Text = {'Chọn phương pháp tìm: '; 'Chia đôi, lặp, Newton'; '(tiếp tuyến)'};
            app.ChnphngphptmChiailpNewtontiptuynEditField = uieditfield(app.NghimTab, 'text');
            app.NhpphngtrnhEditFieldLabel = uilabel(app.NghimTab);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.NhpphngtrnhEditField = uieditfield(app.NghimTab, 'text');
            app.NhpkhongphnlynghimEditFieldLabel = uilabel(app.NghimTab);
            app.NhpkhongphnlynghimEditFieldLabel.Text = {'Nhập khoảng phân ly'; ' nghiệm'};
            app.NhpkhongphnlynghimEditField = uieditfield(app.NghimTab, 'text');
            app.NhpsaischophpEditField_3Label = uilabel(app.NghimTab);
            app.NhpsaischophpEditField_3Label.Text = 'Nhập sai số cho phép';
            app.NhpsaischophpEditField = uieditfield(app.NghimTab, 'text');
            app.UIAxes = uiaxes(app.NghimTab);
            title(app.UIAxes, 'Đồ thị hàm số')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NisuyTab = uitab(app.TabGroup);
            app.NisuyTab.Title = 'Nội suy';
            app.DrawButton_2 = uibutton(app.NisuyTab, 'push');
            app.DrawButton_2.Text = 'Draw';
            app.NhpsaischophpEditField_2Label = uilabel(app.NisuyTab);
            app.NhpsaischophpEditField_2Label.Text = 'Nhập sai số cho phép';
            app.NhpsaischophpEditField_2 = uieditfield(app.NisuyTab, 'numeric');
            app.KtqunghimEditField_2Label = uilabel(app.NisuyTab);
            app.KtqunghimEditField_2Label.Text = 'Kết quả nghiệm';
            app.KtqunghimEditField_2 = uieditfield(app.NisuyTab, 'numeric');
            app.SlnlpEditField_2Label = uilabel(app.NisuyTab);
            app.SlnlpEditField_2Label.Text = 'Số lần lặp';
            app.SlnlpEditField_2 = uieditfield(app.NisuyTab, 'numeric');
            app.ChnphngphptmChiailpNewtontiptuynEditField_2Label = uilabel(app.NisuyTab);
            app.ChnphngphptmChiailpNewtontiptuynEditField_2Label.Text = {'Chọn phương pháp tìm: '; 'Chia đôi, lặp, Newton'; '(tiếp tuyến)'};
            app.ChnphngphptmChiailpNewtontiptuynEditField_2 = uieditfield(app.NisuyTab, 'text');
            app.NhpphngtrnhEditField_2Label = uilabel(app.NisuyTab);
            app.NhpphngtrnhEditField_2Label.Text = 'Nhập phương trình';
            app.NhpphngtrnhEditField_2 = uieditfield(app.NisuyTab, 'text');
            app.NhpkhongphnlynghimEditField_2Label = uilabel(app.NisuyTab);
            app.NhpkhongphnlynghimEditField_2Label.Text = {'Nhập khoảng phân ly'; ' nghiệm'};
            app.NhpkhongphnlynghimEditField_2 = uieditfield(app.NisuyTab, 'text');
            app.UIAxes_2 = uiaxes(app.NisuyTab);
            title(app.UIAxes_2, 'Đồ thị hàm số')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CK_Ten

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end